classdef KuriosGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ArchivoMenu                 matlab.ui.container.Menu
        GaleriaMenu                 matlab.ui.container.Menu
        ImportarSecuenciaMenu       matlab.ui.container.Menu
        ExportarSecuenciaMenu       matlab.ui.container.Menu
        SalirMenu                   matlab.ui.container.Menu
        ConexionMenu                matlab.ui.container.Menu
        ConectarcamaraMenu          matlab.ui.container.Menu
        DesconectarcamaraMenu       matlab.ui.container.Menu
        ConectarfiltroMenu          matlab.ui.container.Menu
        DesconectarfiltroMenu       matlab.ui.container.Menu
        AyudaMenu                   matlab.ui.container.Menu
        AcercadeMenu                matlab.ui.container.Menu
        Toolbar                     matlab.ui.container.Toolbar
        triggerDir                  matlab.ui.container.toolbar.ToggleTool
        previewToggle               matlab.ui.container.toolbar.ToggleTool
        captureToolbar              matlab.ui.container.toolbar.PushTool
        gallery                     matlab.ui.container.toolbar.PushTool
        mainGrid                    matlab.ui.container.GridLayout
        TabGroup                    matlab.ui.container.TabGroup
        ManualTab                   matlab.ui.container.Tab
        GridLayout4                 matlab.ui.container.GridLayout
        ControldelacamaraPanel      matlab.ui.container.Panel
        manualCamGrid               matlab.ui.container.GridLayout
        FramerateSpinner            matlab.ui.control.Spinner
        FPSSlider                   matlab.ui.control.Slider
        FPSSliderLabel              matlab.ui.control.Label
        cameraModeDropdown          matlab.ui.control.DropDown
        ModoLabel                   matlab.ui.control.Label
        camGainSlider               matlab.ui.control.Slider
        camShutterSlider            matlab.ui.control.Slider
        camFramespertriggerSpinner  matlab.ui.control.Spinner
        FotosportriggerLabel        matlab.ui.control.Label
        camShutterSpinner           matlab.ui.control.Spinner
        tdeexposicinmsLabel         matlab.ui.control.Label
        camGainSpinner              matlab.ui.control.Spinner
        gananciadBLabel             matlab.ui.control.Label
        ControldelfiltroPanel       matlab.ui.container.Panel
        manualBwMode                matlab.ui.control.DropDown
        ModoLabel_2                 matlab.ui.control.Label
        wavelengthSpinner           matlab.ui.control.Spinner
        colorBar                    matlab.ui.control.Image
        wavelengthSlider            matlab.ui.control.Slider
        SecuenciaTab                matlab.ui.container.Tab
        GridLayout2                 matlab.ui.container.GridLayout
        controldesecuenciaPanel     matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        triggerExtButton            matlab.ui.control.Button
        CaptureSeqButton            matlab.ui.control.Button
        TriggerSwitch               matlab.ui.control.ToggleSwitch
        TriggerSwitchLabel          matlab.ui.control.Label
        agregarelementosPanel       matlab.ui.container.Panel
        GridLayout6                 matlab.ui.container.GridLayout
        agregarButton               matlab.ui.control.Button
        shuttermsSpinner            matlab.ui.control.Spinner
        shuttermsSpinnerLabel       matlab.ui.control.Label
        gaindBSpinner               matlab.ui.control.Spinner
        gaindBSpinnerLabel          matlab.ui.control.Label
        mododeBWDropDown            matlab.ui.control.DropDown
        mododeBWDropDownLabel       matlab.ui.control.Label
        tiempomsSpinner             matlab.ui.control.Spinner
        tiempomsSpinnerLabel        matlab.ui.control.Label
        ldeondanmSpinner            matlab.ui.control.Spinner
        ldeondanmSpinnerLabel       matlab.ui.control.Label
        sequenceTable               matlab.ui.control.Table
        EstadoPanel                 matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        camLamp                     matlab.ui.control.Lamp
        CamaraLampLabel             matlab.ui.control.Label
        filterLamp                  matlab.ui.control.Lamp
        FiltroLampLabel             matlab.ui.control.Label
        Preview                     matlab.ui.control.UIAxes
        sequenceContextMenu         matlab.ui.container.ContextMenu
        NuevoelementoMenu           matlab.ui.container.Menu
        EliminarseleccionMenu       matlab.ui.container.Menu
        EscogercomodefaultMenu      matlab.ui.container.Menu
        EliminartodoMenu            matlab.ui.container.Menu
    end


    properties (Access = public)
        filterHandle                % instancia al filtro
        camHandle                   % instancia a la camara
        camSrc                      % configuracion camara
        filterFlag  = 0;            % flag del filtro 1:on 0:off
        camFlag     = 0;            % flag de la camara 1:on 0:0ff
        seqLength                   % Largo actual de la secuencia
        table_x                     % coordenada x de la tabla de secuencia
        table_y                     % coordenada y de la tabla de secuencia
        cameraModes = {
            'F7_Raw16_1920x1200_Mode7';...
            'F7_Mono12_1920x1200_Mode0';...
            'F7_Mono12_1920x1200_Mode7';...
            'F7_Mono12_960x600_Mode1';...
            'F7_Mono16_1920x1200_Mode0';...
            'F7_Mono16_1920x1200_Mode7';...
            'F7_Mono16_960x600_Mode1';...
            'F7_Mono8_1920x1200_Mode0';...
            'F7_Mono8_1920x1200_Mode7';...
            'F7_Mono8_960x600_Mode1';...
            'F7_Raw12_1920x1200_Mode0';...
            'F7_Raw12_1920x1200_Mode7';...
            'F7_Raw12_960x600_Mode1';...
            'F7_Raw16_1920x1200_Mode0';...
            'F7_Raw16_960x600_Mode1';...
            'F7_Raw8_1920x1200_Mode0';...
            'F7_Raw8_1920x1200_Mode7';...
            'F7_Raw8_960x600_Mode1'
            }                       % modos de la camara de video
        galleryWindow               % Instancia de la ventana de analisis
    end

    methods (Access = public)

        function initFilter(app)
            % esta funcion se encarga de inicializar el filtro ajustable
            % tambien se ajustan los controles de la GUI segun sea el caso
            try
                app.filterHandle = Kurios();
                % si la conexion fue exitosa:
                if app.filterHandle.isOperative
                    app.filterHandle.setOutputMode(1);  % modo manual
                    app.filterFlag = 1;
                end
            catch
                % si ocurrio algun error en la conexion
                app.filterFlag = 0;
            end
            app.limitFilterControls % bloqueo/desbloqueo de botones GUI
        end

        function initCam(app, mode)
            % esta funcion se encarga de inicializar la camara de video en
            % un modo default, tambien limita los controles en la gui
            % cuando es necesario.
            imaqreset   % se reinicializa los drivers de camaras en matlab
            try
                app.camHandle = videoinput('pointgrey', 1, mode);
                app.camSrc = getselectedsource(app.camHandle);

                app.captureToolbar.Enable = 'on';
                app.camFlag = 1;
                app.camLamp.Color = [0 1 0];     % lampara green
            catch ME
                disp("[+] error al iniciar la camara, codigo: ")
                disp(ME)
                app.captureToolbar.Enable = 'off';
                app.camLamp.Color = [1 0 0];        % lampara red
                app.camFlag = 0;
            end
            app.limitCamControls;   % bloqueo/desbloqueo de botones GUI
        end

        function uploadTable(app)
            % esta funcion carga las tablas desde la GUI hacia el filtro de
            % ser necesario.
            app.filterHandle.deleteSequenceStep(0);     % primero se limpia la tabla en el filtro
            t = app.sequenceTable.Data;
            for i=t(:,1)'
                app.filtro.insertSequenceStep(i,t(i,2),t(i,3),t(i,4));
            end
        end

        function addSequence(app,wavelength,time,bwmode,gain,shutter)
            % esta funcion agrega elementos a la tabla de secuencias
            n = app.seqLength + 1;
            newCol = [n,wavelength,time,bwmode,gain,shutter];
            app.sequenceTable.Data(n,:) = newCol;
            app.seqLength = n;
        end

        function performSequence(app)
            % esta funcion toma la tabla de secuencias y captura cada una
            % de los elementos en esta, se utiliza el modo manual ya que
            % des esta forma es mas sencillo capturar cada frame.
            scroll(app.sequenceTable,"top");
            s1 = uistyle('BackgroundColor',[0, 0.4470, 0.7410]);
            wavelengths = app.sequenceTable.Data.wavelength';
            % parseo modo de BW
            modesRaw =  app.sequenceTable.Data.bwmode';
            modes = [];
            for k=modesRaw
                aux=char(k);
                if strcmp(aux,'BLACK')
                    modes = [modes 1];
                elseif strcmp(aux,'WIDE')
                    modes = [modes 2];
                elseif strcmp(aux,'MEDIUM')
                    modes = [modes 4];
                elseif strcmp(aux,'NARROW')
                    modes = [modes 8];
                end
            end
            gains = app.sequenceTable.Data.gain';
            shutters = app.sequenceTable.Data.shutter';
            delays = app.sequenceTable.Data.time';

            camOut = propinfo(app.camHandle);
            X = zeros(camOut.VideoResolution.DefaultValue(2),camOut.VideoResolution.DefaultValue(1),app.seqLength);
            timeStamp = zeros(1,app.seqLength);
            % comienza adquisicion
            app.filterHandle.setWavelength(420);    %limite inf
            pause(200/1000);        % 200ms de ajuste
            tic
            for i = 1:app.seqLength
                addStyle(app.sequenceTable,s1,'row',i);
                app.filterHandle.setWavelength(wavelengths(i));
                app.filterHandle.setBwMode(modes(i));
                pause(delays(i)/1000)
%                 pause(1)
                app.camSrc.Gain = gains(i);
                app.camSrc.Shutter = shutters(i);
                caption = getsnapshot(app.camHandle);
                timeStamp(i) = toc;
                X(:,:,i) = caption;
                removeStyle(app.sequenceTable);
            end
            seqLenght = app.seqLength;

%             hcube = hypercube(X,wavelengths);
            ima = X;
            wavelength = wavelengths;
            % guardado
            defname = strcat(date,"_",string(wavelengths(1)),"_",string(wavelengths(end)));
            filter = {'*.mat'};
            [file, path] = uiputfile(filter,"Guardar secuencia",defname);
            if file==0
                return
            else
                uiprogressdlg(app.UIFigure,...
                    'Title','Espere un momento',...
                    'Message','Guardando la imagen',...
                    'Indeterminate','on');
                save(strcat(path, file),...
                    "ima", "wavelength",...
                    "timeStamp","modesRaw","gains","shutters","seqLenght",...
                    '-v7.3');
            end
            
        end

        function initSequenceTable(app)
            % construccion de la tabla de secuencias
            load('defaultTable.mat', 't')
            t.bwmode = categorical(t.bwmode, ...
                {'BLACK','WIDE','MEDIUM','NARROW'},'Ordinal',true);
            [app.seqLength, ~] = size(t);
            app.sequenceTable.Data = t;
            app.sequenceTable.ColumnName = {'n','wavelenght','time','BW','gain','shutter'};
            app.sequenceTable.ColumnWidth = {10,80,80,80,60,70};
            app.sequenceTable.ColumnEditable = [false, true true, true, true, true];
            % otros
            app.gaindBSpinner.Value = 10;
            app.shuttermsSpinner.Value = 0.005;
            app.shuttermsSpinner.Step = 0.001;
        end

        function limitFilterControls(app)
            % esta funcion ajusta los elementos graficos de la GUI en caso
            % de estar el filtro disponible o no.
            if app.filterFlag
                app.filterLamp.Color = [0 1 0];     % lampara green
                % modo manual
                app.wavelengthSlider.Limits = [420, 730];
                app.wavelengthSpinner.Step = 1;
                app.wavelengthSpinner.Limits = [420, 730];
                app.manualBwMode.Items = {'WIDE','MEDIUM','NARROW','BLACK'};

                % modo secuencia
                app.ldeondanmSpinner.Limits = [420, 730];
                app.tiempomsSpinner.Limits = [1, 60000];
                app.mododeBWDropDown.Items = {'WIDE','BLACK','MEDIUM','NARROW'};
                app.gaindBSpinner.Limits = [0.000, 29.996];
                app.shuttermsSpinner.Limits = [0.005, 42.092];
                % menu y barra de herramientas
                app.ControldelfiltroPanel.Enable = 'on';
                app.ConectarfiltroMenu.Enable = 'off';
                app.DesconectarfiltroMenu.Enable = 'on';
                % setGUI
%                 wav = app.filterHandle.getWavelength;
                app.filterHandle.setBwMode(2);
                app.wavelengthSlider.Value = 550;
                app.wavelengthSpinner.Value = 550;
            else
                app.filterLamp.Color = [1 0 0];     % lampara red
                app.ControldelfiltroPanel.Enable = 'off';
                app.ConectarfiltroMenu.Enable = 'on';
                app.DesconectarfiltroMenu.Enable = 'off';
            end
        end

        function limitCamControls(app)
            % esta funcion activa/desactiva los elementos graficos de la
            % camara de video dependiendo si esta se encuentra o no
            if app.camFlag
                srcOut = propinfo(app.camSrc);
                % panel modo manual
                app.camShutterSpinner.Limits = [srcOut.Shutter.ConstraintValue(1),srcOut.Shutter.ConstraintValue(2)];
                app.camShutterSlider.Limits = [srcOut.Shutter.ConstraintValue(1),srcOut.Shutter.ConstraintValue(2)]; %miliseconds
                app.camGainSpinner.Limits = [srcOut.Gain.ConstraintValue(1),srcOut.Gain.ConstraintValue(2)];
                app.camGainSlider.Limits = [srcOut.Gain.ConstraintValue(1),srcOut.Gain.ConstraintValue(2)];
                app.camFramespertriggerSpinner.Limits = [1,5];
                app.cameraModeDropdown.Items = app.cameraModes';
                app.FPSSlider.Limits = [srcOut.FrameRate.ConstraintValue(1),srcOut.FrameRate.ConstraintValue(2)];
                app.FramerateSpinner.Limits = [srcOut.FrameRate.ConstraintValue(1),srcOut.FrameRate.ConstraintValue(2)];

                % menu conexion
                app.ControldelacamaraPanel.Enable = 'on';
                app.ConectarcamaraMenu.Enable = 'off';
                app.DesconectarcamaraMenu.Enable = 'on';
                app.previewToggle.Enable = 'on';
                % condiciones iniciales
                gain = srcOut.Gain.DefaultValue; %db
                app.camGainSlider.Value = gain;
                app.camGainSpinner.Value = gain;
                shutter = srcOut.Shutter.DefaultValue; %ms
                app.camShutterSlider.Value = shutter;
                app.camShutterSpinner.Value = shutter;
                FramesPerTrigger = 1; % 1 frame por cada trigger
                app.camFramespertriggerSpinner.Value = FramesPerTrigger;
                framerate = srcOut.FrameRate.DefaultValue;
                app.FramerateSpinner.Value = framerate;
                app.FPSSlider.Value = framerate;

            else
                app.ControldelacamaraPanel.Enable = 'off';
                app.ConectarcamaraMenu.Enable = 'on';
                app.DesconectarcamaraMenu.Enable = 'off';
                app.previewToggle.State = 'off';
                app.previewToggle.Enable = 'off';  
            end
        end

        function deinitCam(app)
            % esta funcion deinicializa la camara de forma correcta
            stoppreview(app.camHandle);
            stop(app.camHandle);
            app.camFlag = 0;
            app.limitCamControls;
            app.camLamp.Color = [1 0 0];        % lampara red
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % maquillaje de la preview
            image(app.Preview,ones(1920,1200,3));
            app.Preview.XLim = [0,1920];
            app.Preview.YLim = [0,1200];
            app.Preview.XTick = [];
            app.Preview.YTick = [];
            % secuencia de inicializacion
            app.initFilter;                         % instancia al filtro
            app.initCam(app.cameraModes{1});        % instancia a la camara
            % tabla de secuencias
            app.seqLength = 0;
            app.initSequenceTable;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % este callback esta encargado de cambiar el modo de salida del
            % filtro al navegar entre pestañas (en caso de estar el filtro disponible).
            selectedTab = app.TabGroup.SelectedTab;
            if strcmp(selectedTab.Title,"Manual") && app.filterFlag
                app.filterHandle.setOutputMode(1);
            elseif strcmp(selectedTab.Title,"Secuencia") && app.filterFlag
                % **se utiliza modo manual por sencillez, en estricto rigor 
                % se debe utilizar el modo secuencia con trigger externo y utilizar
                % filt.forceTrigger para cambiar de elemento.
                app.filterHandle.setOutputMode(1);   % se asume trigger interno
            elseif strcmp(selectedTab.Title, "Analogo") && app.filterFlag
                app.filterHandle.setOutputMode(4);   % se asume trigger interno
            end
        end

        % Clicked callback: captureToolbar
        function CapturarButtonPushed(app, event)
            % esta funcion captura un frame con la camara y lo guarda, se
            % debe revisar el formato establecido, el boton solo estara
            % disponible cuando la camara lo este.
            caption = getsnapshot(app.camHandle);
            filter = {'*.png';'*.tif'};
            [file, path] = uiputfile(filter);
            if file==0
                return
            else
                imwrite(caption, strcat(path,file));
            end
            
        end

        % Menu selected function: EliminartodoMenu
        function EliminartodoMenuSelected(app, event)
            % esta funcion elimina todos los elementos de la tabla de
            % secuencias
            app.seqLength = 0;
            app.sequenceTable.Data(:,:) = [];
            if app.filterFlag
                app.filterHandle.deleteSequenceStep(0);
            end
        end

        % Menu selected function: NuevoelementoMenu
        function NuevoelementoMenuSelected(app, event)
            % esta funcion agrega un elemento a la tabla
            app.addSequence(550,200,{'BLACK'},20,200)
        end

        % Button pushed function: CaptureSeqButton
        function CaptureSeqButtonPushed(app, event)
            % esta funcion realiza una captura hiperespectral utilizando el
            % filtro y la camara.
            app.performSequence;
        end

        % Button pushed function: agregarButton
        function agregarButtonPushed(app, event)
            % esta funcion agrega un elemento a la tabla de secuencias
            % utilizando el formulario de la pestaña secuencia
            wavelength = app.ldeondanmSpinner.Value;
            time = app.tiempomsSpinner.Value;
            bwmode = app.mododeBWDropDown.Value;
            gain = app.gaindBSpinner.Value;
            shutter = app.shuttermsSpinner.Value;
            app.addSequence(wavelength, time, {bwmode}, gain, shutter);
        end

        % Menu selected function: ImportarSecuenciaMenu
        function ImportarSecuenciaMenuSelected(app, event)
            % esta funcion permite importar una tabla de secuencias desde
            % un archivo .mat o un excel
            filter = {'*.xlsx';'*.mat'};
            [file, path] = uigetfile(filter);
            if file==0
                return
            end
            uiprogressdlg(app.UIFigure,...
                'Title','Espere un momento',...
                'Message','Cargando tabla de secuencia',...
                'Indeterminate','on');

            [~,~,ext] = fileparts(file);
            if strcmp('.mat',ext)
                load(strcat(path,file), 'aux');
                app.sequenceTable.Data = aux;
                [app.seqLength, ~] = size(aux);
            elseif strcmp(ext,'.xlsx')
                app.sequenceTable.Data = readtable(strcat(path,file));
                app.seqLength = length(app.sequenceTable.Data.wavelength)
            end
            %             if app.filterFlag
            %                 app.uploadTable();
            %             end
        end

        % Menu selected function: ExportarSecuenciaMenu
        function ExportarSecuenciaMenuSelected(app, event)
            % esta funcion permite exportar la tabla de secuencias a un
            % archivo .mat o un excel
            filter = {'*.mat';'*.xlsx'};
            [file, ~] = uiputfile(filter);
            if file==0
                return
            end
            uiprogressdlg(app.UIFigure,...
                'Title','Espere un momento',...
                'Message','Guardando tabla de secuencias',...
                'Indeterminate','on');
            [~,~,ext] = fileparts(file);
            if strcmp('.mat',ext)
                aux = app.sequenceTable.Data;
                save(file,"aux");
            elseif strcmp('.xlsx',ext)
                writetable(app.sequenceTable.Data, file);
            end
        end

        % Menu selected function: SalirMenu
        function SalirMenuSelected(app, event)
            % callback dedicada a cerrar la gui a traves del menu
            closereq();
        end

        % Menu selected function: GaleriaMenu
        function GaleriaMenuSelected(app, event)
            % este callback realiza una instancia a la ventana de analisis
            app.galleryWindow = gallery;
        end

        % Menu selected function: ConectarcamaraMenu
        function ConectarcamaraMenuSelected(app, event)
            % este callback se agrego para asociar una funcion a un
            % elemento grafico
            app.initCam(app.cameraModes{1});
        end

        % Menu selected function: DesconectarcamaraMenu
        function DesconectarcamaraMenuSelected(app, event)
            % este callback se agrego para asociar una funcion a un
            % elemento grafico
            app.deinitCam;
        end

        % Menu selected function: ConectarfiltroMenu
        function ConectarfiltroMenuSelected(app, event)
            % este callback se agrego para asociar una funcion a un
            % elemento grafico
            app.initFilter;
        end

        % Menu selected function: DesconectarfiltroMenu
        function DesconectarfiltroMenuSelected(app, event)
            % este callback se agrego para asociar una funcion a un
            % elemento grafico y limita la GUI            
            delete(app.filterHandle)
            app.filterFlag = 0;
            app.limitFilterControls;
            app.filterLamp.Color = [1 0 0];     % lampara red
        end

        % Menu selected function: AcercadeMenu
        function AcercadeMenuSelected(app, event)
            % esta funcion realiza una instancia a la ventana de "acerca
            % de"
        end

        % Value changed function: manualBwMode
        function manualBwModeValueChanged(app, event)
            % este callback ajusta el filtro ajustable en el modo manual
            value = app.manualBwMode.Value;
            if strcmp(value, 'WIDE')
                app.filterHandle.setBwMode(2);
            elseif strcmp(value, 'MEDIUM')
                app.filterHandle.setBwMode(4);
            elseif strcmp(value, 'NARROW')
                app.filterHandle.setBwMode(8);
            elseif strcmp(value, 'BLACK')
                app.filterHandle.setBwMode(1);
            end
        end

        % Value changed function: wavelengthSlider
        function wavelengthSliderValueChanged(app, event)
            % este callback ajusta el filtro ajustable en el modo manual
            value = app.wavelengthSlider.Value;
            value = round(value);
            app.wavelengthSpinner.Value = value;
            app.filterHandle.setWavelength(value);
        end

        % Value changed function: wavelengthSpinner
        function wavelengthSpinnerValueChanged(app, event)
            % este callback ajusta el filtro ajustable en el modo manual
            value = app.wavelengthSpinner.Value;
            app.wavelengthSlider.Value = value;
            app.filterHandle.setWavelength(value);
        end

        % Off callback: triggerDir
        function triggerDirOff(app, event)
            app.triggerDir.Icon = 'img/normalTrig.png';
        end

        % On callback: triggerDir
        function triggerDirOn(app, event)
            app.triggerDir.Icon = 'img/invertedTrig.png';
        end

        % Value changed function: camShutterSpinner
        function camShutterSpinnerValueChanged(app, event)
            % esta funcion ajusta la camara en modo manual
            value = app.camShutterSpinner.Value;
            app.camShutterSlider.Value = value;
            app.camSrc.Shutter = value;
        end

        % Value changed function: camShutterSlider
        function camShutterSliderValueChanged(app, event)
            % esta funcion ajusta la camara en modo manual
            value = app.camShutterSlider.Value;
            app.camShutterSpinner.Value = value;
            app.camSrc.Shutter = value;
        end

        % Value changed function: camGainSpinner
        function camGainSpinnerValueChanged(app, event)
            % esta funcion ajusta la camara en modo manual
            value = app.camGainSpinner.Value;
            app.camGainSlider.Value = value;
            app.camSrc.Gain = value;
        end

        % Value changed function: camGainSlider
        function camGainSliderValueChanged(app, event)
            % esta funcion ajusta la camara en modo manual
            value = app.camGainSlider.Value;
            app.camGainSpinner.Value = value;
            app.camSrc.Gain = value;
        end

        % Button pushed function: triggerExtButton
        function triggerExtButtonPushed(app, event)
            % este callback fuerza el trigger del filtro ajustable
            app.filterHandle.forceTrigger();
        end

        % Value changed function: camFramespertriggerSpinner
        function camFramespertriggerSpinnerValueChanged(app, event)
            % este callback ajusta la camara en modo manual
            value = app.camFramespertriggerSpinner.Value;
            app.camFramespertriggerSpinner.Value = value;
            app.camSrc.FramesPerTrigger = value;
        end

        % Callback function
        function camFramesPerTriggerSliderValueChanged(app, event)
            % este callback ajusta la camara en modo manual
            value = app.camFramesPerTriggerSlider.Value;
            app.camFramespertriggerSpinner.Value = value;
            app.camSrc.FramesPerTrigger = value;
        end

        % Menu selected function: EscogercomodefaultMenu
        function EscogercomodefaultMenuSelected(app, event)
            % esta funcion escoge un valor por default para cada columna de
            % la tabla de secuencia, se toma el valor seleccionado para el
            % cambio. (click derecho en el elemento)
            data = app.sequenceTable.Data(app.table_x,app.table_y);
            if app.table_y==2
                %                     app.filtro.setDefaultWavelength(data)
                app.sequenceTable.Data(:,2) = data;
            elseif app.table_y==3
                %                     app.filtro.setDefaultTs(data)
                app.sequenceTable.Data(:,3) = data;
            elseif app.table_y==4
                %                     app.filtro.setDefaultBwMode(data)
                app.sequenceTable.Data(:,4) = data;
            elseif app.table_y==5
                app.sequenceTable.Data(:,5) = data;
            elseif app.table_y==6
                app.sequenceTable.Data(:,6) = data;
            end
        end

        % Cell selection callback: sequenceTable
        function sequenceTableCellSelection(app, event)
            % este callback obtiene las coordenadas del elemento seleccionado de la tabla de secuencias 
            indices = event.Indices;
            app.table_x = indices(1);app.table_y = indices(2);
        end

        % Menu selected function: EliminarseleccionMenu
        function EliminarseleccionMenuSelected(app, event)
            % checar este callback
            if app.filterFlag
                app.filtro.deleteSequenceStep(app.table_x);
            end
            app.sequenceTable.Data(app.table_x,:) = [];
        end

        % On callback: previewToggle
        function previewToggleOn(app, event)
            % activar la preview de la camara
            camOut = propinfo(app.camHandle);
            resolution = camOut.VideoResolution.DefaultValue;
            hImage = image(app.Preview,zeros(resolution(1),resolution(2),3));
            app.Preview.XLim = [0,resolution(1)];
            app.Preview.YLim = [0,resolution(2)];
            app.Preview.XTick = [];
            app.Preview.YTick = [];
            pbaspect(app.Preview, [resolution(1),resolution(2),1]);
            preview(app.camHandle, hImage);
            start(app.camHandle);
        end

        % Off callback: previewToggle
        function previewToggleOff(app, event)
            % detener la preview de la camara
            stoppreview(app.camHandle);
            image(app.Preview,ones(1920,1200,3));
        end

        % Callback function
        function camDebugButtonPushed(app, event)
            % este callback se utiliza para el debug de la interfaz
            disp("[+++++++++ camera debug +++++++++]")
            camOut = propinfo(app.camHandle);
            srcOut = propinfo(app.camSrc);
            fprintf("camera mode: %s \n", camOut.VideoFormat.DefaultValue)
            fprintf("video resolution: %d X %d \n", camOut.VideoResolution.DefaultValue(1),camOut.VideoResolution.DefaultValue(2))
            fprintf("fps: %s \n", srcOut.FrameRate.DefaultValue)
            disp("############# Limites ###########")
            fprintf("limites gain: %d - %d \n",srcOut.Gain.ConstraintValue(1),srcOut.Gain.ConstraintValue(2))
            fprintf("limites framerate: %d - %d \n",srcOut.FrameRate.ConstraintValue(1),srcOut.FrameRate.ConstraintValue(2))
        end

        % Value changed function: cameraModeDropdown
        function cameraModeDropdownValueChanged(app, event)
            % este callback se encarga de escoger el modo de salida de la
            % camara de video
            value = app.cameraModeDropdown.Value;
            app.deinitCam;
            app.initCam(value);
        end

        % Clicked callback: gallery
        function galleryClicked(app, event)
            % esta funcion realiza una instancia a la ventana de analisis
            % utilizando el boton en la barra de herramientas
            app.galleryWindow = gallery;
        end

        % Value changed function: FramerateSpinner
        function FramerateSpinnerValueChanged(app, event)
            value = app.FramerateSpinner.Value;
            app.FPSSlider.Value = value;
            app.camSrc.FrameRate = value;
        end

        % Value changed function: FPSSlider
        function FPSSliderValueChanged(app, event)
            value = app.FPSSlider.Value;
            app.FramerateSpinner.Value = value;
            app.camSrc.FrameRate = value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.galleryWindow)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ArchivoMenu = uimenu(app.UIFigure);
            app.ArchivoMenu.Text = 'Archivo';
            app.GaleriaMenu = uimenu(app.ArchivoMenu);
            app.GaleriaMenu.MenuSelectedFcn = createCallbackFcn(app, @GaleriaMenuSelected, true);
            app.GaleriaMenu.Text = 'Galeria';
            app.ImportarSecuenciaMenu = uimenu(app.ArchivoMenu);
            app.ImportarSecuenciaMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportarSecuenciaMenuSelected, true);
            app.ImportarSecuenciaMenu.Separator = 'on';
            app.ImportarSecuenciaMenu.Text = 'Importar Secuencia';
            app.ExportarSecuenciaMenu = uimenu(app.ArchivoMenu);
            app.ExportarSecuenciaMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportarSecuenciaMenuSelected, true);
            app.ExportarSecuenciaMenu.Text = 'Exportar Secuencia';
            app.SalirMenu = uimenu(app.ArchivoMenu);
            app.SalirMenu.MenuSelectedFcn = createCallbackFcn(app, @SalirMenuSelected, true);
            app.SalirMenu.Separator = 'on';
            app.SalirMenu.Text = 'Salir';
            app.ConexionMenu = uimenu(app.UIFigure);
            app.ConexionMenu.Text = 'Conexión';
            app.ConectarcamaraMenu = uimenu(app.ConexionMenu);
            app.ConectarcamaraMenu.MenuSelectedFcn = createCallbackFcn(app, @ConectarcamaraMenuSelected, true);
            app.ConectarcamaraMenu.Text = 'Conectar camara';
            app.DesconectarcamaraMenu = uimenu(app.ConexionMenu);
            app.DesconectarcamaraMenu.MenuSelectedFcn = createCallbackFcn(app, @DesconectarcamaraMenuSelected, true);
            app.DesconectarcamaraMenu.Text = 'Desconectar camara';
            app.ConectarfiltroMenu = uimenu(app.ConexionMenu);
            app.ConectarfiltroMenu.MenuSelectedFcn = createCallbackFcn(app, @ConectarfiltroMenuSelected, true);
            app.ConectarfiltroMenu.Separator = 'on';
            app.ConectarfiltroMenu.Text = 'Conectar filtro';
            app.DesconectarfiltroMenu = uimenu(app.ConexionMenu);
            app.DesconectarfiltroMenu.MenuSelectedFcn = createCallbackFcn(app, @DesconectarfiltroMenuSelected, true);
            app.DesconectarfiltroMenu.Text = 'Desconectar filtro';
            app.AyudaMenu = uimenu(app.UIFigure);
            app.AyudaMenu.Text = 'Ayuda';
            app.AcercadeMenu = uimenu(app.AyudaMenu);
            app.AcercadeMenu.MenuSelectedFcn = createCallbackFcn(app, @AcercadeMenuSelected, true);
            app.AcercadeMenu.Text = 'Acerca de';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.triggerDir = uitoggletool(app.Toolbar);
            app.triggerDir.Icon = 'normalTrig.png';
            app.triggerDir.OffCallback = createCallbackFcn(app, @triggerDirOff, true);
            app.triggerDir.OnCallback = createCallbackFcn(app, @triggerDirOn, true);
            app.previewToggle = uitoggletool(app.Toolbar);
            app.previewToggle.Icon = 'preview.png';
            app.previewToggle.OffCallback = createCallbackFcn(app, @previewToggleOff, true);
            app.previewToggle.OnCallback = createCallbackFcn(app, @previewToggleOn, true);
            app.captureToolbar = uipushtool(app.Toolbar);
            app.captureToolbar.ClickedCallback = createCallbackFcn(app, @CapturarButtonPushed, true);
            app.captureToolbar.Icon = 'shutter.png';
            app.gallery = uipushtool(app.Toolbar);
            app.gallery.ClickedCallback = createCallbackFcn(app, @galleryClicked, true);
            app.gallery.Icon = 'pngkey.com-png-images-gallery-2309675.png';
            app.mainGrid = uigridlayout(app.UIFigure);
            app.Preview = uiaxes(app.mainGrid);
            title(app.Preview, 'CamPreview')
            xlabel(app.Preview, 'X')
            ylabel(app.Preview, 'Y')
            zlabel(app.Preview, 'Z')
            app.EstadoPanel = uipanel(app.mainGrid);
            app.EstadoPanel.Title = 'Estado';
            app.GridLayout5 = uigridlayout(app.EstadoPanel);
            app.FiltroLampLabel = uilabel(app.GridLayout5);
            app.FiltroLampLabel.Text = 'Filtro';
            app.filterLamp = uilamp(app.GridLayout5);
            app.CamaraLampLabel = uilabel(app.GridLayout5);
            app.CamaraLampLabel.Text = 'Camara';
            app.camLamp = uilamp(app.GridLayout5);
            app.TabGroup = uitabgroup(app.mainGrid);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual';
            app.GridLayout4 = uigridlayout(app.ManualTab);
            app.ControldelfiltroPanel = uipanel(app.GridLayout4);
            app.ControldelfiltroPanel.Title = 'Control del filtro';
            app.wavelengthSlider = uislider(app.ControldelfiltroPanel);
            app.wavelengthSlider.Limits = [420 730];
            app.wavelengthSlider.MajorTicks = [];
            app.wavelengthSlider.ValueChangedFcn = createCallbackFcn(app, @wavelengthSliderValueChanged, true);
            app.wavelengthSlider.MinorTicks = [];
            app.wavelengthSlider.Value = 550;
            app.colorBar = uiimage(app.ControldelfiltroPanel);
            app.colorBar.ImageSource = 'colorbar.png';
            app.wavelengthSpinner = uispinner(app.ControldelfiltroPanel);
            app.wavelengthSpinner.ValueChangedFcn = createCallbackFcn(app, @wavelengthSpinnerValueChanged, true);
            app.ModoLabel_2 = uilabel(app.ControldelfiltroPanel);
            app.ModoLabel_2.Text = 'Modo';
            app.manualBwMode = uidropdown(app.ControldelfiltroPanel);
            app.manualBwMode.Items = {'Wide', 'Option 2', 'Option 3', 'Option 4'};
            app.manualBwMode.ValueChangedFcn = createCallbackFcn(app, @manualBwModeValueChanged, true);
            app.manualBwMode.Value = 'Wide';
            app.ControldelacamaraPanel = uipanel(app.GridLayout4);
            app.ControldelacamaraPanel.Title = 'Control de la camara';
            app.manualCamGrid = uigridlayout(app.ControldelacamaraPanel);
            app.gananciadBLabel = uilabel(app.manualCamGrid);
            app.gananciadBLabel.Text = 'ganancia (dB)';
            app.camGainSpinner = uispinner(app.manualCamGrid);
            app.camGainSpinner.ValueChangedFcn = createCallbackFcn(app, @camGainSpinnerValueChanged, true);
            app.tdeexposicinmsLabel = uilabel(app.manualCamGrid);
            app.tdeexposicinmsLabel.Text = 't. de exposición (ms)';
            app.camShutterSpinner = uispinner(app.manualCamGrid);
            app.camShutterSpinner.ValueChangedFcn = createCallbackFcn(app, @camShutterSpinnerValueChanged, true);
            app.FotosportriggerLabel = uilabel(app.manualCamGrid);
            app.FotosportriggerLabel.Text = 'Fotos por trigger';
            app.camFramespertriggerSpinner = uispinner(app.manualCamGrid);
            app.camFramespertriggerSpinner.ValueChangedFcn = createCallbackFcn(app, @camFramespertriggerSpinnerValueChanged, true);
            app.camShutterSlider = uislider(app.manualCamGrid);
            app.camShutterSlider.MajorTicks = [];
            app.camShutterSlider.ValueChangedFcn = createCallbackFcn(app, @camShutterSliderValueChanged, true);
            app.camShutterSlider.MinorTicks = [];
            app.camShutterSlider.Value = 33.4955089820359;
            app.camGainSlider = uislider(app.manualCamGrid);
            app.camGainSlider.MajorTicks = [];
            app.camGainSlider.ValueChangedFcn = createCallbackFcn(app, @camGainSliderValueChanged, true);
            app.camGainSlider.MinorTicks = [];
            app.camGainSlider.Value = 33.4955089820359;
            app.ModoLabel = uilabel(app.manualCamGrid);
            app.ModoLabel.Text = 'Modo';
            app.cameraModeDropdown = uidropdown(app.manualCamGrid);
            app.cameraModeDropdown.Items = {'Raw 1920x1200 16-bit', 'Option 2', 'Option 3', 'Option 4'};
            app.cameraModeDropdown.ValueChangedFcn = createCallbackFcn(app, @cameraModeDropdownValueChanged, true);
            app.cameraModeDropdown.Value = 'Raw 1920x1200 16-bit';
            app.FPSSliderLabel = uilabel(app.manualCamGrid);
            app.FPSSliderLabel.Text = 'FPS';
            app.FPSSlider = uislider(app.manualCamGrid);
            app.FPSSlider.MajorTicks = [];
            app.FPSSlider.MajorTickLabels = {''};
            app.FPSSlider.ValueChangedFcn = createCallbackFcn(app, @FPSSliderValueChanged, true);
            app.FPSSlider.MinorTicks = [];
            app.FramerateSpinner = uispinner(app.manualCamGrid);
            app.FramerateSpinner.ValueChangedFcn = createCallbackFcn(app, @FramerateSpinnerValueChanged, true);
            app.SecuenciaTab = uitab(app.TabGroup);
            app.SecuenciaTab.Title = 'Secuencia';
            app.GridLayout2 = uigridlayout(app.SecuenciaTab);
            app.sequenceTable = uitable(app.GridLayout2);
            app.sequenceTable.ColumnName = '';
            app.sequenceTable.RowName = {};
            app.sequenceTable.CellSelectionCallback = createCallbackFcn(app, @sequenceTableCellSelection, true);
            app.agregarelementosPanel = uipanel(app.GridLayout2);
            app.agregarelementosPanel.Title = 'agregar elementos';
            app.GridLayout6 = uigridlayout(app.agregarelementosPanel);
            app.ldeondanmSpinnerLabel = uilabel(app.GridLayout6);
            app.ldeondanmSpinnerLabel.Text = 'l. de onda (nm)';
            app.ldeondanmSpinner = uispinner(app.GridLayout6);
            app.tiempomsSpinnerLabel = uilabel(app.GridLayout6);
            app.tiempomsSpinnerLabel.Text = 'tiempo (ms)';
            app.tiempomsSpinner = uispinner(app.GridLayout6);
            app.mododeBWDropDownLabel = uilabel(app.GridLayout6);
            app.mododeBWDropDownLabel.Text = 'modo de  BW';
            app.mododeBWDropDown = uidropdown(app.GridLayout6);
            app.gaindBSpinnerLabel = uilabel(app.GridLayout6);
            app.gaindBSpinnerLabel.Text = 'gain (dB)';
            app.gaindBSpinner = uispinner(app.GridLayout6);
            app.shuttermsSpinnerLabel = uilabel(app.GridLayout6);
            app.shuttermsSpinnerLabel.Text = 'shutter (ms)';
            app.shuttermsSpinner = uispinner(app.GridLayout6);
            app.agregarButton = uibutton(app.GridLayout6, 'push');
            app.agregarButton.ButtonPushedFcn = createCallbackFcn(app, @agregarButtonPushed, true);
            app.agregarButton.Text = 'agregar';
            app.controldesecuenciaPanel = uipanel(app.GridLayout2);
            app.controldesecuenciaPanel.Title = 'control de secuencia';
            app.GridLayout3 = uigridlayout(app.controldesecuenciaPanel);
            app.TriggerSwitchLabel = uilabel(app.GridLayout3);
            app.TriggerSwitchLabel.Text = 'Trigger';
            app.TriggerSwitch = uiswitch(app.GridLayout3, 'toggle');
            app.TriggerSwitch.Items = {'Interno', 'Externo'};
            app.TriggerSwitch.Value = 'Interno';
            app.CaptureSeqButton = uibutton(app.GridLayout3, 'push');
            app.CaptureSeqButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureSeqButtonPushed, true);
            app.CaptureSeqButton.Text = 'CaptureSeq';
            app.triggerExtButton = uibutton(app.GridLayout3, 'push');
            app.triggerExtButton.ButtonPushedFcn = createCallbackFcn(app, @triggerExtButtonPushed, true);
            app.triggerExtButton.Text = 'triggerExt';
            app.sequenceContextMenu = uicontextmenu(app.UIFigure);
            app.NuevoelementoMenu = uimenu(app.sequenceContextMenu);
            app.NuevoelementoMenu.MenuSelectedFcn = createCallbackFcn(app, @NuevoelementoMenuSelected, true);
            app.NuevoelementoMenu.Text = 'Nuevo elemento';
            app.EliminarseleccionMenu = uimenu(app.sequenceContextMenu);
            app.EliminarseleccionMenu.MenuSelectedFcn = createCallbackFcn(app, @EliminarseleccionMenuSelected, true);
            app.EliminarseleccionMenu.Text = 'Eliminar seleccion';
            app.EscogercomodefaultMenu = uimenu(app.sequenceContextMenu);
            app.EscogercomodefaultMenu.MenuSelectedFcn = createCallbackFcn(app, @EscogercomodefaultMenuSelected, true);
            app.EscogercomodefaultMenu.Text = 'Escoger como default';
            app.EliminartodoMenu = uimenu(app.sequenceContextMenu);
            app.EliminartodoMenu.MenuSelectedFcn = createCallbackFcn(app, @EliminartodoMenuSelected, true);
            app.EliminartodoMenu.Separator = 'on';
            app.EliminartodoMenu.Text = 'Eliminar todo';
            % Assign app.sequenceContextMenu
            app.sequenceTable.ContextMenu = app.sequenceContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KuriosGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end