classdef gallery < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VentanadeanlisisUIFigure    matlab.ui.Figure
        ArchivoMenu                 matlab.ui.container.Menu
        AbrirdirectorioMenu         matlab.ui.container.Menu
        CargarcuboMenu              matlab.ui.container.Menu
        GuardarfalseColorMenu       matlab.ui.container.Menu
        GuardarcuboMenu             matlab.ui.container.Menu
        GuardarfirmaMenu            matlab.ui.container.Menu
        CerrarMenu                  matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        TabGroup                    matlab.ui.container.TabGroup
        FirmaEspectralTab           matlab.ui.container.Tab
        GridLayout4                 matlab.ui.container.GridLayout
        AjustarejesButton           matlab.ui.control.Button
        GraficarButton              matlab.ui.control.Button
        ySpinner                    matlab.ui.control.Spinner
        ySpinnerLabel               matlab.ui.control.Label
        xSpinner                    matlab.ui.control.Spinner
        xSpinnerLabel               matlab.ui.control.Label
        BandaTab                    matlab.ui.container.Tab
        GridLayout7                 matlab.ui.container.GridLayout
        backButton                  matlab.ui.control.Button
        BandaDropDown               matlab.ui.control.DropDown
        BandaDropDownLabel          matlab.ui.control.Label
        nextButton                  matlab.ui.control.Button
        PreTab                      matlab.ui.container.Tab
        GridLayout8                 matlab.ui.container.GridLayout
        calibracion                 matlab.ui.control.Button
        Spinner                     matlab.ui.control.Spinner
        SpinnerLabel                matlab.ui.control.Label
        RotarButton                 matlab.ui.control.Button
        FiltrarRuidoButton          matlab.ui.control.Button
        EcualizarhistogramaButton   matlab.ui.control.Button
        SegmentacionTab             matlab.ui.container.Tab
        GridLayout5                 matlab.ui.container.GridLayout
        UmbralSpinner               matlab.ui.control.Spinner
        UmbralSpinnerLabel          matlab.ui.control.Label
        MaxiteracionesSpinner       matlab.ui.control.Spinner
        MaxiteracionesSpinnerLabel  matlab.ui.control.Label
        GuardarmapaButton           matlab.ui.control.Button
        NdeclustersSpinner          matlab.ui.control.Spinner
        NdeclustersSpinnerLabel     matlab.ui.control.Label
        EjecutarButton              matlab.ui.control.Button
        RecortarTab                 matlab.ui.container.Tab
        GridLayout6                 matlab.ui.container.GridLayout
        recortarButton              matlab.ui.control.Button
        ySpinner_2                  matlab.ui.control.Spinner
        ySpinner_2Label             matlab.ui.control.Label
        xSpinner_2                  matlab.ui.control.Spinner
        xSpinner_2Label             matlab.ui.control.Label
        anchoSpinner                matlab.ui.control.Spinner
        anchoSpinnerLabel           matlab.ui.control.Label
        altoSpinner                 matlab.ui.control.Spinner
        altoSpinnerLabel            matlab.ui.control.Label
        MarcarButton                matlab.ui.control.Button
        Tree                        matlab.ui.container.Tree
        Node                        matlab.ui.container.TreeNode
        Node2                       matlab.ui.container.TreeNode
        Node3                       matlab.ui.container.TreeNode
        Node4                       matlab.ui.container.TreeNode
        UIAxes_2                    matlab.ui.control.UIAxes
        ContextMenu                 matlab.ui.container.ContextMenu
        GuardarimagencomoMenu       matlab.ui.container.Menu
        contextMenuFolder           matlab.ui.container.ContextMenu
        abrirdirectorioMenu         matlab.ui.container.Menu
        ContextMenu2                matlab.ui.container.ContextMenu
        ActualizarMenu              matlab.ui.container.Menu
    end


    properties (Access = public)
        gains
        modes
        shutters
        times
        hcube
        title % Description
        colorFalseima % Description
        singleBandIma % Description
        resolution % Description
        folder
        sMap % Description
        seqLength % Description
        dropdownItems % Description
        dropdownCount % Description
    end
    
    methods (Access = public)
        
        function loadimage(app)
            % update window data
%             app.UIAxes_2.Title.String = app.title;
%             app.gains = gains;
%             app.modes = modesRaw;
%             app.shutters = shutters;
%             app.times = timeStamp;
%             app.hcube = hcube;

            % update figure preview
            app.singleBandIma = app.hcube.DataCube(:,:,1);
            app.resolution = size(app.singleBandIma);

            % band dropdown
            app.seqLength = length(app.hcube.Wavelength);
            for i=1:app.seqLength
                bandItems(i) = {num2str(app.hcube.Wavelength(i))};
            end
            app.dropdownItems = bandItems';
            app.BandaDropDown.Items = app.dropdownItems;
            app.dropdownCount = 1;

            % auto false color
            app.loadFalsecolor;

            % limites de firma espectral
            app.xSpinner.Limits = [1,app.resolution(1)];
            app.ySpinner.Limits = [1,app.resolution(2)];

            % limites de recortes
            app.xSpinner_2.Limits = [1, app.resolution(2)];
            app.ySpinner_2.Limits = [1, app.resolution(1)];

            % habilitar botones
            app.GraficarButton.Enable = 'on';
            app.EjecutarButton.Enable = 'on';
        end
        
        function segmentation(app)
            uiprogressdlg(app.VentanadeanlisisUIFigure,...
                'Title','Espere un momento',...
                'Message','Segmentando la imagen',...
                'Indeterminate','on');

%             % PCA
%             numComponents = 8;
%             [outputDataCube, ~] = hyperpca(app.hcube, numComponents,...
%                 'Method', 'eig',...
%                 'MeanCentered',false);
            outputDataCube = app.hcube.DataCube;
%             app.hcube = denoiseNGMeet(app.hcube);
            % kmeans
            [L, ~] = imsegkmeans(single(outputDataCube), app.NdeclustersSpinner.Value,...
                "MaxIterations",app.MaxiteracionesSpinner.Value,...
                "Threshold",app.UmbralSpinner.Value);
            app.sMap = L;
            
            % visualizacion del mapa
            B = labeloverlay(app.colorFalseima, app.sMap);
            imshow(B, [],'Parent', app.UIAxes_2);
            app.UIAxes_2.XLim = [0,app.resolution(2)];
            app.UIAxes_2.YLim = [0,app.resolution(1)];
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.Title.String = "imagen segmentada";
        end
        
        function chFolder(app)
            delete(app.Tree.Children);
            files = dir(app.folder);
            for i= 3:length(files)
                [~,~,ext] = fileparts(files(i).name);
                if files(i).isdir
                    parent = uitreenode(app.Tree,'Text',files(i).name,'Icon','icon/folder.png','Tag','folder');
%                     parent.ContextMenu = app.contextMenuFolder;
                    subfiles = dir(strcat(app.folder,'\',files(i).name));
                    for j=3:length(subfiles)
                        [~,~,ext2] = fileparts(subfiles(j).name);
                        if strcmp(ext2,'.mat')
                            uitreenode(parent,'Text',subfiles(j).name,'Icon','icon/hcube.png','Tag','subfile');
                        elseif subfiles(j).isdir
                            child = uitreenode(parent,'Text',subfiles(j).name,'Icon','icon/folder.png','Tag','folder');
                            child.ContextMenu = app.contextMenuFolder;
                        else
                            continue
                        end
                    end
                elseif strcmp(ext,'.mat')
                    uitreenode(app.Tree,'Text',files(i).name,'Icon','icon/hcube.png','Tag','file');
                else
                    continue
                end
            end
            app.Tree.Multiselect = 'off';
        end
        
        function loadFalsecolor(app)
            app.colorFalseima = [];
            %% metodo carretero
%             app.colorFalseima = colorize(app.hcube,"Method","rgb","ContrastStretching",true);
%             app.colorFalseima = double(app.colorFalseima);
            %% metodo guapo
            % seleccion de bandas
            % estas bandas podrian no estar en el dataset lo cual causaria
            % error, revisar mas tarde pliss

            % imatest 2
%             Red = find(app.hcube.Wavelength==670);%675
%             Green = find(app.hcube.Wavelength==570);
%             Blue = find(app.hcube.Wavelength==450);

            % imatest 1
            Red = find(app.hcube.Wavelength==615);%670
            Green = find(app.hcube.Wavelength==550);%570
            Blue = find(app.hcube.Wavelength==480);%450

            R = app.hcube.DataCube(:,:,Red)./max(max(app.hcube.DataCube(:,:,Red)));
            G = app.hcube.DataCube(:,:,Green)./max(max(app.hcube.DataCube(:,:,Green)));
            B = app.hcube.DataCube(:,:,Blue)./max(max(app.hcube.DataCube(:,:,Blue)));
            % ajuste de contraste
            R = imadjust(R,stretchlim(R),[]);
            G = imadjust(G,stretchlim(G),[]);
            B = imadjust(B,stretchlim(B),[]);
            app.colorFalseima = cat(3, R, G, B);

            %% visualizacion
            imshow(app.colorFalseima, [],'Parent', app.UIAxes_2);
%             app.UIAxes_2.XLim = [0,app.resolution(2)];
%             app.UIAxes_2.YLim = [0,app.resolution(1)];
%             app.UIAxes_2.XTick = [];
%             app.UIAxes_2.YTick = [];
%             app.UIAxes_2.Title.String = "False color";
%             app.UIAxes_2.XLabel.String = "";
%             app.UIAxes_2.YLabel.String = "";
        end
        
        function rotate(app)
            ima = imrotate(app.hcube.DataCube,90);
            app.hcube = hypercube(ima,app.hcube.Wavelength);
            app.loadFalsecolor;
        end
        
        function plotSpectrum(app)
            app.UIAxes_2.XLabel.String = "\lambda";
            app.UIAxes_2.YLabel.String = "A/D 16-bit";
            x = app.xSpinner.Value;
            y = app.ySpinner.Value;
            wav = app.hcube.Wavelength;
            L = length(wav);
            spectra = reshape(app.hcube.DataCube(x,y,1:L),[1,L]);

            reset(app.UIAxes_2)
            plot(wav, spectra, 'Parent', app.UIAxes_2);
            app.UIAxes_2.Title.String = strcat("Firma espectral en: [",string(x),",",string(y),"]");
            app.UIAxes_2.XMinorGrid = 'on'; app.UIAxes_2.YMinorGrid = 'on';
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % carga directorio default
            app.folder = '.';
            app.chFolder;

            % botones que no deben funcionar sin imagen
            app.GuardarmapaButton.Enable = 'off';
            app.GraficarButton.Enable = 'off';
            app.EjecutarButton.Enable = 'off';
        end

        % Value changed function: BandaDropDown
        function BandaDropDownValueChanged(app, event)
            value = app.bandaDropDown.Value;
            indx = find(app.hcube.Wavelength==str2num(value));
            app.dropdownCount = indx;
            ima = app.hcube.DataCube(:,:,indx);
            ima = double(ima);
            imshow(ima,[],'Parent',app.UIAxes_2);
            app.UIAxes_2.XLabel.String = strcat("\lambda:",string(app.hcube.Wavelength(indx)));%," time:",string(app.times(indx)));
            app.UIAxes_2.Title.String = "Una banda";
            %app.gainLabel.Text = string(app.gains(indx));
        end

        % Menu selected function: CargarcuboMenu
        function CargarcuboMenuSelected(app, event)
            [file, path] = uigetfile;
            uiprogressdlg(app.VentanadeanlisisUIFigure,...
                'Title','Espere un momento',...
                'Message','Cargando el cubo',...
                'Indeterminate','on');

            load(strcat(path,file));
            app.hcube = hypercube(ima,wavelength);
            app.loadimage;
        end

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            app.segmentation;
            app.GuardarmapaButton.Enable = 'on';
        end

        % Menu selected function: AbrirdirectorioMenu
        function AbrirdirectorioMenuSelected(app, event)
            app.folder = uigetdir;
            app.chFolder;
        end

        % Button pushed function: GuardarmapaButton
        function GuardarmapaButtonPushed(app, event)
            [file, path] = uiputfile;
            if file==0
                return
            else
                sMap = app.sMap;
                save(strcat(path,file),"sMap")
            end
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            app.plotSpectrum;
        end

        % Callback function
        function ActualizarButtonPushed(app, event)
            app.chFolder;
        end

        % Menu selected function: CerrarMenu
        function CerrarMenuSelected(app, event)
            closereq;
        end

        % Button pushed function: AjustarejesButton
        function AjustarejesButtonPushed(app, event)
            app.UIAxes_2.XLim = [420, 730];
            app.UIAxes_2.YLim = [0, 65535];
        end

        % Menu selected function: GuardarfalseColorMenu
        function GuardarfalseColorMenuSelected(app, event)
            filtro = "*.tif";
            [file, path] = uiputfile(filtro);
            if file==0
                return
            else
                fColor = app.colorFalseima;
                imwrite(fColor,strcat(path,file))
            end
        end

        % Button pushed function: EcualizarhistogramaButton
        function EcualizarhistogramaButtonPushed(app, event)
            uiprogressdlg(app.VentanadeanlisisUIFigure,...
                'Title','Espere un momento',...
                'Message','Ecualizando el histograma',...
                'Indeterminate','on');
            wavelengths = app.hcube.Wavelength;
            ima = app.hcube.DataCube;
            newCube = zeros(app.resolution(1),app.resolution(2),app.seqLength);
            for i = 1:app.seqLength
                newCube(:,:,i) = histeq(mat2gray(ima(:,:,i)));
            end

            % guardado
            app.hcube = hypercube(newCube,wavelengths);
            app.loadFalsecolor
        end

        % Button pushed function: FiltrarRuidoButton
        function FiltrarRuidoButtonPushed(app, event)
            uiprogressdlg(app.VentanadeanlisisUIFigure,...
                'Title','Espere un momento',...
                'Message','Filtrando el ruido',...
                'Indeterminate','on');
            wavelengths = app.hcube.Wavelength;
            ima = app.hcube.DataCube;

            % filtro median
            for i=1:length(wavelengths)
                newCube(:,:,i) = medfilt2(ima(:,:,i));
            end

            % filtro gaussiano
            newCube = imgaussfilt(newCube, app.Spinner.Value);


            % guardado
            app.hcube = hypercube(newCube,wavelengths);
            app.loadFalsecolor
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;

            uiprogressdlg(app.VentanadeanlisisUIFigure,...
                'Title','Espere un momento',...
                'Message','Cargando el cubo',...
                'Indeterminate','on');

            app.title = selectedNodes.Text;
            if strcmp(get(selectedNodes).Tag,'file')
                load(strcat(app.folder, "\",app.title));
            elseif strcmp(get(selectedNodes).Tag,'subfile')
                load(strcat(app.folder,"\",get(selectedNodes).Parent.Text,'\',app.title));
            elseif strcmp(get(selectedNodes).Tag,'folder')
                return
            end
            
            % construccion del cubox
%             sigma = 2;%prepro
%             ima = imgaussfilt(ima, sigma);%prepro
            app.hcube = hypercube(ima,wavelength);
            app.loadimage;
        end

        % Menu selected function: ActualizarMenu
        function ActualizarMenuSelected(app, event)
            app.chFolder;
        end

        % Menu selected function: abrirdirectorioMenu
        function abrirdirectorioMenuSelected(app, event)
            app.folder = strcat(app.folder,"/",app.Tree.SelectedNodes.Parent.Text, "/", app.Tree.SelectedNodes.Text)
            app.chFolder;
        end

        % Menu selected function: GuardarimagencomoMenu
        function GuardarimagencomoMenuSelected(app, event)
            
        end

        % Button pushed function: MarcarButton
        function MarcarButtonPushed(app, event)
            app.loadFalsecolor;
            region1 = [app.xSpinner_2.Value, app.ySpinner_2.Value, app.anchoSpinner.Value, app.altoSpinner.Value]; % [x y w h] format
            BW1 = false(app.resolution(1), app.resolution(2));
            BW1(region1(2):region1(2)+region1(4),region1(1):region1(1)+region1(3)) = true;
            visboundaries(app.UIAxes_2, BW1, 'Color', 'r');
        end

        % Button pushed function: recortarButton
        function recortarButtonPushed(app, event)
            ima = app.hcube.DataCube(app.ySpinner_2.Value:app.ySpinner_2.Value+app.altoSpinner.Value, ...
                app.xSpinner_2.Value:app.xSpinner_2.Value+app.anchoSpinner.Value,...
                :);
            wavelength = app.hcube.Wavelength;
            [file, path] = uiputfile;
            if file==0
                return
            else
                sMap = app.sMap;
                save(strcat(path,file),"ima","wavelength");
            end
        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            if app.dropdownCount<app.seqLength
                app.dropdownCount = app.dropdownCount + 1;
                app.BandaDropDown.Value = app.dropdownItems(app.dropdownCount);
                app.BandaDropDownValueChanged;
            elseif app.dropdownCount==app.seqLength
                app.nextButton.Enable = 'off'
            end
        end

        % Menu selected function: GuardarcuboMenu
        function GuardarcuboMenuSelected(app, event)
            uiprogressdlg(app.VentanadeanlisisUIFigure,...
                'Title','Espere un momento',...
                'Message','Guardando el cubo',...
                'Indeterminate','on');
            ima = app.hcube.DataCube;
            wavelength = app.hcube.Wavelength;
            [file, path] = uiputfile;
            if file==0
                return
            else
                save(strcat(path,file),"ima","wavelength");
            end
        end

        % Button pushed function: RotarButton
        function RotarButtonPushed(app, event)
            app.rotate;
        end

        % Button pushed function: calibracion
        function calibracionButtonPushed(app, event)
            ima = app.hcube.DataCube;wavelength = app.hcube.Wavelength;
            Ti = 95;
            [M,N,L] = size(ima);
            %% load data
            load compensationData.mat
            %% matriz de blanco
            Wm = ((1/L)*sum(W))*ones(M,N,L);
            %% matriz de negro
            m = (prom2-prom1)/(50-30);
            y = m*Ti-m*30+prom1;
            Bm = y*ones(M,N,L);
            %% expresion compensadora
            ima_compensated = (ima-Bm)./(Wm-Bm);
            %% nuevo cubo
            app.hcube = hypercube(ima_compensated, wavelength);
            app.loadFalsecolor;
        end

        % Menu selected function: GuardarfirmaMenu
        function GuardarfirmaMenuSelected(app, event)
            filtro = "*.tif";
            [file, path] = uiputfile(filtro);
            if file==0
                return
            else
                exportgraphics(app.UIAxes_2,file)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VentanadeanlisisUIFigure = uifigure('Visible', 'off');
            app.VentanadeanlisisUIFigure.Name = 'Ventana de análisis';
            app.ArchivoMenu = uimenu(app.VentanadeanlisisUIFigure);
            app.ArchivoMenu.Text = 'Archivo';
            app.AbrirdirectorioMenu = uimenu(app.ArchivoMenu);
            app.AbrirdirectorioMenu.MenuSelectedFcn = createCallbackFcn(app, @AbrirdirectorioMenuSelected, true);
            app.AbrirdirectorioMenu.Accelerator = 'o';
            app.AbrirdirectorioMenu.Text = 'Abrir directorio';
            app.CargarcuboMenu = uimenu(app.ArchivoMenu);
            app.CargarcuboMenu.MenuSelectedFcn = createCallbackFcn(app, @CargarcuboMenuSelected, true);
            app.CargarcuboMenu.Separator = 'on';
            app.CargarcuboMenu.Text = 'Cargar cubo';
            app.GuardarfalseColorMenu = uimenu(app.ArchivoMenu);
            app.GuardarfalseColorMenu.MenuSelectedFcn = createCallbackFcn(app, @GuardarfalseColorMenuSelected, true);
            app.GuardarfalseColorMenu.Text = 'Guardar falseColor';
            app.GuardarcuboMenu = uimenu(app.ArchivoMenu);
            app.GuardarcuboMenu.MenuSelectedFcn = createCallbackFcn(app, @GuardarcuboMenuSelected, true);
            app.GuardarcuboMenu.Accelerator = 's';
            app.GuardarcuboMenu.Text = 'Guardar cubo';
            app.GuardarfirmaMenu = uimenu(app.ArchivoMenu);
            app.GuardarfirmaMenu.MenuSelectedFcn = createCallbackFcn(app, @GuardarfirmaMenuSelected, true);
            app.GuardarfirmaMenu.Text = 'Guardar firma';
            app.CerrarMenu = uimenu(app.ArchivoMenu);
            app.CerrarMenu.MenuSelectedFcn = createCallbackFcn(app, @CerrarMenuSelected, true);
            app.CerrarMenu.Separator = 'on';
            app.CerrarMenu.Accelerator = 'x';
            app.CerrarMenu.Text = 'Cerrar';
            app.GridLayout = uigridlayout(app.VentanadeanlisisUIFigure);
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Tree = uitree(app.GridLayout);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.FirmaEspectralTab = uitab(app.TabGroup);
            app.FirmaEspectralTab.Title = 'Firma Espectral';
            app.GridLayout4 = uigridlayout(app.FirmaEspectralTab);
            app.xSpinnerLabel = uilabel(app.GridLayout4);
            app.xSpinnerLabel.Text = 'x';
            app.xSpinner = uispinner(app.GridLayout4);
            app.xSpinner.Value = 960;
            app.ySpinnerLabel = uilabel(app.GridLayout4);
            app.ySpinnerLabel.Text = 'y';
            app.ySpinner = uispinner(app.GridLayout4);
            app.ySpinner.Value = 600;
            app.GraficarButton = uibutton(app.GridLayout4, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.AjustarejesButton = uibutton(app.GridLayout4, 'push');
            app.AjustarejesButton.ButtonPushedFcn = createCallbackFcn(app, @AjustarejesButtonPushed, true);
            app.AjustarejesButton.Text = 'Ajustar ejes';
            app.BandaTab = uitab(app.TabGroup);
            app.BandaTab.Title = 'Banda';
            app.GridLayout7 = uigridlayout(app.BandaTab);
            app.nextButton = uibutton(app.GridLayout7, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Text = 'next';
            app.BandaDropDownLabel = uilabel(app.GridLayout7);
            app.BandaDropDownLabel.Text = 'Banda';
            app.BandaDropDown = uidropdown(app.GridLayout7);
            app.BandaDropDown.Items = {'450', 'Option 2', 'Option 3', 'Option 4'};
            app.BandaDropDown.ValueChangedFcn = createCallbackFcn(app, @BandaDropDownValueChanged, true);
            app.BandaDropDown.Value = '450';
            app.backButton = uibutton(app.GridLayout7, 'push');
            app.backButton.Text = 'back';
            app.PreTab = uitab(app.TabGroup);
            app.PreTab.Title = 'Pre';
            app.GridLayout8 = uigridlayout(app.PreTab);
            app.EcualizarhistogramaButton = uibutton(app.GridLayout8, 'push');
            app.EcualizarhistogramaButton.ButtonPushedFcn = createCallbackFcn(app, @EcualizarhistogramaButtonPushed, true);
            app.EcualizarhistogramaButton.Text = 'Ecualizar histograma';
            app.FiltrarRuidoButton = uibutton(app.GridLayout8, 'push');
            app.FiltrarRuidoButton.ButtonPushedFcn = createCallbackFcn(app, @FiltrarRuidoButtonPushed, true);
            app.FiltrarRuidoButton.Text = 'Filtrar Ruido';
            app.RotarButton = uibutton(app.GridLayout8, 'push');
            app.RotarButton.ButtonPushedFcn = createCallbackFcn(app, @RotarButtonPushed, true);
            app.RotarButton.Text = 'Rotar';
            app.SpinnerLabel = uilabel(app.GridLayout8);
            app.SpinnerLabel.Text = 'σ';
            app.Spinner = uispinner(app.GridLayout8);
            app.Spinner.Step = 0.1;
            app.Spinner.Value = 2;
            app.calibracion = uibutton(app.GridLayout8, 'push');
            app.calibracion.ButtonPushedFcn = createCallbackFcn(app, @calibracionButtonPushed, true);
            app.calibracion.Text = 'Normalizar';
            app.SegmentacionTab = uitab(app.TabGroup);
            app.SegmentacionTab.Title = 'Segmentacion';
            app.GridLayout5 = uigridlayout(app.SegmentacionTab);
            app.EjecutarButton = uibutton(app.GridLayout5, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.NdeclustersSpinnerLabel = uilabel(app.GridLayout5);
            app.NdeclustersSpinnerLabel.Text = 'N° de clusters';
            app.NdeclustersSpinner = uispinner(app.GridLayout5);
            app.NdeclustersSpinner.Limits = [2 Inf];
            app.NdeclustersSpinner.Value = 2;
            app.GuardarmapaButton = uibutton(app.GridLayout5, 'push');
            app.GuardarmapaButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarmapaButtonPushed, true);
            app.GuardarmapaButton.Text = 'Guardar mapa';
            app.MaxiteracionesSpinnerLabel = uilabel(app.GridLayout5);
            app.MaxiteracionesSpinnerLabel.Text = 'Max iteraciones';
            app.MaxiteracionesSpinner = uispinner(app.GridLayout5);
            app.MaxiteracionesSpinner.Limits = [10 Inf];
            app.MaxiteracionesSpinner.Value = 100;
            app.UmbralSpinnerLabel = uilabel(app.GridLayout5);
            app.UmbralSpinnerLabel.Text = 'Umbral';
            app.UmbralSpinner = uispinner(app.GridLayout5);
            app.UmbralSpinner.Limits = [-Inf 1];
            app.UmbralSpinner.Value = 0.0001;
            app.RecortarTab = uitab(app.TabGroup);
            app.RecortarTab.Title = 'Recortar';
            app.GridLayout6 = uigridlayout(app.RecortarTab);
            app.MarcarButton = uibutton(app.GridLayout6, 'push');
            app.MarcarButton.ButtonPushedFcn = createCallbackFcn(app, @MarcarButtonPushed, true);
            app.MarcarButton.Text = 'Marcar';
            app.altoSpinnerLabel = uilabel(app.GridLayout6);
            app.altoSpinnerLabel.Text = 'alto';
            app.altoSpinner = uispinner(app.GridLayout6);
            app.altoSpinner.Value = 128;
            app.anchoSpinnerLabel = uilabel(app.GridLayout6);
            app.anchoSpinnerLabel.Text = 'ancho';
            app.anchoSpinner = uispinner(app.GridLayout6);
            app.anchoSpinner.Value = 128;
            app.xSpinner_2Label = uilabel(app.GridLayout6);
            app.xSpinner_2Label.Text = 'x';
            app.xSpinner_2 = uispinner(app.GridLayout6);
            app.ySpinner_2Label = uilabel(app.GridLayout6);
            app.ySpinner_2Label.Text = 'y';
            app.ySpinner_2 = uispinner(app.GridLayout6);
            app.recortarButton = uibutton(app.GridLayout6, 'push');
            app.recortarButton.ButtonPushedFcn = createCallbackFcn(app, @recortarButtonPushed, true);
            app.recortarButton.Text = 'recortar';
            app.ContextMenu = uicontextmenu(app.VentanadeanlisisUIFigure);
            app.GuardarimagencomoMenu = uimenu(app.ContextMenu);
            app.GuardarimagencomoMenu.MenuSelectedFcn = createCallbackFcn(app, @GuardarimagencomoMenuSelected, true);
            app.GuardarimagencomoMenu.Text = 'Guardar imagen como';
            app.contextMenuFolder = uicontextmenu(app.VentanadeanlisisUIFigure);
            app.abrirdirectorioMenu = uimenu(app.contextMenuFolder);
            app.abrirdirectorioMenu.MenuSelectedFcn = createCallbackFcn(app, @abrirdirectorioMenuSelected, true);
            app.abrirdirectorioMenu.Text = 'abrir directorio';
            % Assign app.contextMenuFolder
            app.Node.ContextMenu = app.contextMenuFolder;
            app.ContextMenu2 = uicontextmenu(app.VentanadeanlisisUIFigure);
            app.ActualizarMenu = uimenu(app.ContextMenu2);
            app.ActualizarMenu.MenuSelectedFcn = createCallbackFcn(app, @ActualizarMenuSelected, true);
            app.ActualizarMenu.Text = 'Actualizar';
            % Assign app.ContextMenu2
            app.Tree.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.VentanadeanlisisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gallery

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VentanadeanlisisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VentanadeanlisisUIFigure)
        end
    end
end